classdef Cw < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        EqualiserPanel      matlab.ui.container.Panel
        HzSlider_2Label     matlab.ui.control.Label
        HzSlider_2          matlab.ui.control.Slider
        HzSliderLabel       matlab.ui.control.Label
        HzSlider            matlab.ui.control.Slider
        HzSlider_3Label     matlab.ui.control.Label
        HzSlider_3          matlab.ui.control.Slider
        HzSlider_4Label     matlab.ui.control.Label
        HzSlider_4          matlab.ui.control.Slider
        KHzSliderLabel      matlab.ui.control.Label
        KHzSlider           matlab.ui.control.Slider
        KHzLabel            matlab.ui.control.Label
        KHzSlider_2         matlab.ui.control.Slider
        KHzLabel_2          matlab.ui.control.Label
        KHzSlider_3         matlab.ui.control.Slider
        KHzLabel_3          matlab.ui.control.Label
        KHzSlider_4         matlab.ui.control.Slider
        KHzLabel_4          matlab.ui.control.Label
        KHzSlider_5         matlab.ui.control.Slider
        ampSlider           matlab.ui.control.Slider
        HzSliderLabel_2     matlab.ui.control.Label
        HzSlider_5Label     matlab.ui.control.Label
        HzSlider_5          matlab.ui.control.Slider
        EqualisePeakButton  matlab.ui.control.Button
        EqfourButton        matlab.ui.control.Button
        PlayPanel           matlab.ui.container.Panel
        PlayButton          matlab.ui.control.Button
        StopButton          matlab.ui.control.Button
        LoadFileButton      matlab.ui.control.Button
        SavePresetButton    matlab.ui.control.Button
        LoadPresetButton    matlab.ui.control.Button
        EffectsPanel        matlab.ui.container.Panel
        WahWahButton        matlab.ui.control.Button
        FlangerButton       matlab.ui.control.Button
        ChorusButton        matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        UIAxesSpectrum      matlab.ui.control.UIAxes
    end


    methods (Access = private)
         
        function y = peakfilt(~, x, Wc, Wb, G)
            % y = peakfilt (x, Wc, Wb, G)
            % Author: M. Holters
            % Applies a peak filter to the input signal x.
            % Wc is the normalized center frequency 0<Wc<1, i.e. 2*fc/fS.
            % Wb is the normalized bandwidth 0<Wb<1, i.e. 2*fb/fS.
            % G is the gain in dB.
            
            V0 = 10^(G/20); H0 = V0 - 1;
            if G >= 0
              c = (tan(pi*Wb/2)-1) / (tan(pi*Wb/2)+1);     % boost
            else
              c = (tan(pi*Wb/2)-V0) / (tan(pi*Wb/2)+V0);   % cut
            end
            d = -cos(pi*Wc);
            xh = [0, 0];
            for n = 1:length(x)
              xh_new = x(n) - d*(1-c)*xh(1) + c*xh(2);
              ap_y = -c * xh_new + d*(1-c)*xh(1) + xh(2);
              xh = [xh_new, xh(1)];
              y(n) = 0.5 * H0 * (x(n) - ap_y) + x(n);
            end
        end
        
        function y = lowshelving (~, x, Wc, G)
            % y = lowshelving (x, Wc, G)
            % Author: M. Holters
            % Applies a low-frequency shelving filter to the input signal x.
            % Wc is the normalized cut-off frequency 0<Wc<1, i.e. 2*fc/fS.
            % G is the gain in dB
            
            V0 = 10^(G/20); H0 = V0 - 1;
            if G >= 0
                c = (tan(pi*Wc/2)-1) / (tan(pi*Wc/2)+1);     % boost
            else
                c = (tan(pi*Wc/2)-V0) / (tan(pi*Wc/2)+V0);   % cut
            end
            xh = 0;
            
            for n = 1:length(x)
                xh_new = x(n) - c*xh;
                ap_y = c * xh_new + xh;
                xh = xh_new;
                y(n) = 0.5 * H0 * (x(n) - ap_y) + x(n);  % change to minus for HS
            end
        end
        
        function y = highshelving (~, x, Wc, G)
            % y = highshelving (x, Wc, G)
            % Author: M. Holters
            % Applies a low-frequency shelving filter to the input signal x.
            % Wc is the normalized cut-off frequency 0<Wc<1, i.e. 2*fc/fS
            % G is the cut in dB
            
            V0 = 10^(G/20); H0 = V0 - 1;
            if G >= 0
                c = (tan(pi*Wc/2)-1) / (tan(pi*Wc/2)+1);     % boost
            else
                c = (V0*tan(pi*Wc/2)-1) / (V0*tan(pi*Wc/2)+1);   % cut
            end
            xh = 0;
            
            for n = 1:length(x)
                xh_new = x(n) - c*xh;
                ap_y = c * xh_new + xh;
                xh = xh_new;
                y(n) = 0.5 * H0 * (x(n) + ap_y) + x(n);  % changed to minus for HS
            end
        end
        
        function LoadData(app)
            [fileName, pathName] = uigetfile(['*.','txt'],'Load Slider Levels');
            if (fileName ~= 0)
                data = load([pathName, fileName], '-mat');
                app.ampSlider.Value = data.sliderLevels(1);
                app.HzSlider.Value = data.sliderLevels(2);
                app.HzSlider_2.Value = data.sliderLevels(3);
                app.HzSlider_3.Value = data.sliderLevels(4);
                app.HzSlider_4.Value = data.sliderLevels(5);
                app.HzSlider_5.Value = data.sliderLevels(6);
                app.KHzSlider.Value = data.sliderLevels(7);
                app.KHzSlider_2.Value = data.sliderLevels(8);
                app.KHzSlider_3.Value = data.sliderLevels(9);
                app.KHzSlider_4.Value = data.sliderLevels(10);    
                app.KHzSlider_5.Value = data.sliderLevels(11);
            end
        end
        
        function saveData(app)
            var1 = app.ampSlider.Value;
            var2 = app.HzSlider.Value;
            var3 = app.HzSlider_2.Value;
            var4 = app.HzSlider_3.Value;
            var5 = app.HzSlider_4.Value;
            var6 = app.HzSlider_5.Value;
            var7 = app.KHzSlider.Value;
            var8 = app.KHzSlider_2.Value;
            var9 = app.KHzSlider_3.Value;
            var10 = app.KHzSlider_4.Value;            
            var11 = app.KHzSlider_5.Value;
            
            sliderLevels = [var1, var2, var3, var4, var5, var6, var7, var8, var9, var10, var11];
            [fileName,pathName] = uiputfile(['sliders.','txt'],'Save slider levels');
            if (fileName ~= 0)
                save([pathName,fileName],'sliderLevels');
            end
        end
        
        function plotSpectra(app, y)
            Fs = getappdata(app.UIAxes, 'sampRate');
            
            N = 16000;
            steps = (0:N-1)*(Fs/N);
            fourier = abs(fft(y,N))/N;
            %fourierCentered = abs(fftshift(fourier)/N);
            
            base = mean(y);
            %decibels = 20 * log10(fourierCentered/base);
            decibels = 20 * log10(fourier/base);
            plot(app.UIAxesSpectrum, steps, decibels);
        end
        
        function plotReg(app,y)
            Fs = getappdata(app.UIAxes, 'sampRate');
            len = length(y);
            xAxis = (0:len-1)/Fs; 
            plot(app.UIAxes, xAxis, y);
        end
        
        function newY = fourierFilter(app, y, G, sF, eF)
            %Take fourier transform -> Filter it in freq domain -> Take
            %inverse fourier
            Fs = getappdata(app.UIAxes, 'sampRate');
            G = 10^(G/20);
            N = 16348;
            fourier = fft(y);
            freqs = (0:N-1)*(Fs/N);
            
            newY=fourier;
            
            mask=find((freqs>=sF*(Fs/N))&(freqs<=eF*(Fs/N)));
            newY(mask) = fourier(mask)*G;
            newY = ifft(newY);
            newY = real(newY);
        end
        
        function y = wahwah(app,x)
            %Taken from the CM0268 MATLAB DSP Graphics Slides 
            Fs = getappdata(app.UIAxes, 'sampRate');
            
            %damping factor
            %Lower the damping factor the smaller the pass band
            damp = 0.05;
            
            % min and max centre cutoff frequency of variable bandpass
            % filter
            minf=500;
            maxf=3000;
            
            %wah frequency, how many Hz per second are cycled through
            Fw = 3000;
            
            % change in centre frequency per sample (Hz)
            delta = Fw/Fs;
            
            % create triangle wave of centre frequency values
            Fc=minf:delta:maxf;
            while(length(Fc) < length(x))
                Fc = [Fc (maxf:-delta:minf)];
                Fc = [Fc (minf:delta:maxf)];
            end
            
            % trim tri wave to size of input
            Fc = Fc(1:length(x));
            
            %difference equation coefficients
            
            F1 = 2*sin(pi*(Fc(1)/Fs)); %must be recalculated each time Fc changes
            Q1 = 2*damp; % dictates the size of the pass bands
            
            yh=zeros(size(x)); %preallocating vectors
            yb=zeros(size(x));
            yl=zeros(size(x));
            
            % first sample, to avoid referencing of negative signals
            yh(1) = x(1);
            yb(1) = F1*yh(1);
            yl(1) = F1*yb(1);
            
            % apply difference equation to the sample
            for n=2:length(x)
                yh(n) = x(n) - yl(n-1) - Q1*yb(n-1);
                yb(n) = F1*yh(n) + yb(n-1);
                yl(n) = F1*yb(n) + yl(n-1);
                F1 = 2*sin((pi*Fc(n))/Fs);
            end
            
            %normalise
            maxyb = max(abs(yb));
            y = yb/maxyb;
        end
        
        function y = flanger(app,x)
            %y(n) = x(n) + gx[x - M(n)]
            %M(n) = M0 * [1 + A*sin(2*pi*n*(f/Fs))]
            %f = flang freq
            %A = sweep/excursion (not sure what this means)
            %M0 = average delay length
            %g = depth control (should be set to 1 for maximum effect)
            %(also -1 for inverted mode)
            
            Fs = getappdata(app.UIAxes, 'sampRate');
            
            f = 0.3; %can be from 0.1 to 1hz
            g = 0.8;
            M0 = 0.008*Fs; %the book says 0ms but surely that's wrong?
            A = 0.5; %not sure what to set this to
            
            
            y = zeros(1,length(x));
            for i =1:M0
                y(i) = x(i);
            end
            
            for n = (round(M0)+1):length(x)
                M = abs(M0 *(A*sin(2*pi*n*(f/Fs)))); %adding a 1 causes indexing errors
                M = round(M);
                y(n) = x(n) + g*x(n - M); 
            end
        end
        
        function y = chorus(app,x)
            %same as flanging but with a longer delay
            Fs = getappdata(app.UIAxes, 'sampRate');
            
            f = 0.3;
            g = 0.8;
            M0 = 0.05*Fs;
            A = 0.5; %not sure what to set this to
            
            
            y = zeros(1,length(x));
            for i =1:M0
                y(i) = x(i);
            end
            
            for n = (round(M0)+1):length(x)
                M = abs(M0 *(A*sin(2*pi*n*(f/Fs))));
                M = round(M);
                y(n) = x(n) + g*x(n - M); 
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [file, path] = uigetfile({"*.mp3; *.wav;*.acc;*.flac", "Audio Files (*.mp3, *.wav, *.acc, *.flac"}, "Select a sound file.");
            if (file ~= 0)
                [y, Fs] = audioread(strcat(path, file));
                setappdata(app.UIAxes, 'sound', y);
                setappdata(app.UIAxes, 'Eqsound', y);
                setappdata(app.UIAxes, 'sampRate', Fs);
                plotReg(app,y);
                plotSpectra(app, y);
            end
            
        end

        % Button pushed function: EqualisePeakButton
        function EqualisePeakButtonPushed(app, event)
            y = getappdata(app.UIAxes, 'sound');
            Fs = getappdata(app.UIAxes, 'sampRate');

            %low shelving
            y1 = lowshelving(app, y, (2*32)/Fs, app.HzSlider.Value);
            
            %peaking
            y2 = peakfilt(app, y1, 2*(64)/Fs, (2*64)/Fs, app.HzSlider_2.Value);
            y3 = peakfilt(app, y2, 2*(128)/Fs, (2*128)/Fs, app.HzSlider_3.Value); 
            y4 = peakfilt(app, y3, 2*(256)/Fs, (2*256)/Fs, app.HzSlider_4.Value); 
            y5 = peakfilt(app, y4, 2*(512)/Fs, (2*512)/Fs, app.HzSlider_5.Value);
            y6 = peakfilt(app, y5, 2*(1024)/Fs, (2*1024)/Fs, app.KHzSlider.Value);
            y7 = peakfilt(app, y6, 2*(2048)/Fs, (2*2048)/Fs, app.KHzSlider_2.Value);
            y8 = peakfilt(app, y7, 2*(4096)/Fs, (2*4096)/Fs, app.KHzSlider_3.Value);
            y9 = peakfilt(app, y8, 2*(8192)/Fs, (2*8192)/Fs, app.KHzSlider_4.Value);
            
            %high shelving
            y10 = highshelving(app, y9, (2*16348)/Fs, app.KHzSlider_5.Value);
            
            %amp
            y11 = y10*(app.ampSlider.Value/100);
            
            setappdata(app.UIAxes, 'Eqsound', y11);
            plotReg(app,y11);
            plotSpectra(app, y11);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            y = getappdata(app.UIAxes, 'Eqsound');
            Fs = getappdata(app.UIAxes, 'sampRate');
            sound(y, Fs);
        end

        % Button pushed function: SavePresetButton
        function SavePresetButton_2Pushed(app, event)
            saveData(app);
        end

        % Button pushed function: LoadPresetButton
        function LoadPresetButton_2Pushed(app, event)
            LoadData(app);
        end

        % Button pushed function: EqfourButton
        function EqualisefourierButtonPushed(app, event)
            y = getappdata(app.UIAxes, 'sound');
            
            
            var2 = app.HzSlider.Value;
            var3 = app.HzSlider_2.Value;
            var4 = app.HzSlider_3.Value;
            var5 = app.HzSlider_4.Value;
            var6 = app.KHzSlider.Value;
            var7 = app.KHzSlider_2.Value;
            var8 = app.KHzSlider_3.Value;
            var9 = app.KHzSlider_4.Value;            
            var10 = app.KHzSlider_5.Value;
            
       
            y1 = fourierFilter(app,y,var2,16,48);
            y2 = fourierFilter(app,y1,var3,32,96);
            y3 = fourierFilter(app,y2,var4,64,192);
            y4 = fourierFilter(app,y3,var5,128,384);
            y5 = fourierFilter(app,y4,var6,512,768);
            y6 = fourierFilter(app,y5,var7,1024,1536);
            y7 = fourierFilter(app,y6,var8,2048,3076);
            y8 = fourierFilter(app,y7,var9,4096,6144);
            y9 = fourierFilter(app,y8,var10,8192,12288);
            y10 = fourierFilter(app,y9,var10,16384,24576);
            y11 = y10*(app.ampSlider.Value/100);
            
            cla(app.UIAxes);
            cla(app.UIAxesSpectrum);
            
            setappdata(app.UIAxes, 'Eqsound', y11);
            plotReg(app, y11);
            plotSpectra(app, y11);
        end

        % Button pushed function: WahWahButton
        function WahWahButtonPushed(app, event)
             y = getappdata(app.UIAxes, 'sound');
             
             yNew = wahwah(app,y);
             yNew = yNew*(app.ampSlider.Value/100);
             setappdata(app.UIAxes, 'Eqsound', yNew);
             plotReg(app, yNew);
             plotSpectra(app, yNew);
        end

        % Button pushed function: FlangerButton
        function FlangerButtonPushed(app, event)
             y = getappdata(app.UIAxes, 'sound');
             yNew = flanger(app,y);
             
             yNew = yNew*(app.ampSlider.Value/100);
             setappdata(app.UIAxes, 'Eqsound', yNew);
             plotReg(app, yNew);
             plotSpectra(app, yNew);
        end

        % Size changed function: EffectsPanel
        function EffectsPanelSizeChanged(app, event)
            position = app.EffectsPanel.Position;
            
        end

        % Button pushed function: ChorusButton
        function ChorusButtonPushed(app, event)
            y = getappdata(app.UIAxes, 'sound');
            yNew = chorus(app,y);
            
            yNew = yNew*(app.ampSlider.Value/100);
            setappdata(app.UIAxes, 'Eqsound', yNew);
            plotReg(app, yNew);
            plotSpectra(app, yNew);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            clear sound;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EqualiserPanel = uipanel(app.UIFigure);
            app.EqualiserPanel.Title = 'Equaliser ';
            app.HzSlider_2Label = uilabel(app.EqualiserPanel);
            app.HzSlider_2Label.Text = '62.5Hz';
            app.HzSlider_2 = uislider(app.EqualiserPanel);
            app.HzSlider_2.Limits = [-6 6];
            app.HzSliderLabel = uilabel(app.EqualiserPanel);
            app.HzSliderLabel.Text = '31.25Hz';
            app.HzSlider = uislider(app.EqualiserPanel);
            app.HzSlider.Limits = [-6 6];
            app.HzSlider_3Label = uilabel(app.EqualiserPanel);
            app.HzSlider_3Label.Text = '125Hz';
            app.HzSlider_3 = uislider(app.EqualiserPanel);
            app.HzSlider_3.Limits = [-6 6];
            app.HzSlider_4Label = uilabel(app.EqualiserPanel);
            app.HzSlider_4Label.Text = '250Hz';
            app.HzSlider_4 = uislider(app.EqualiserPanel);
            app.HzSlider_4.Limits = [-6 6];
            app.KHzSliderLabel = uilabel(app.EqualiserPanel);
            app.KHzSliderLabel.Text = '1KHz';
            app.KHzSlider = uislider(app.EqualiserPanel);
            app.KHzSlider.Limits = [-6 6];
            app.KHzLabel = uilabel(app.EqualiserPanel);
            app.KHzLabel.Text = '2KHz';
            app.KHzSlider_2 = uislider(app.EqualiserPanel);
            app.KHzSlider_2.Limits = [-6 6];
            app.KHzLabel_2 = uilabel(app.EqualiserPanel);
            app.KHzLabel_2.Text = '4KHz';
            app.KHzSlider_3 = uislider(app.EqualiserPanel);
            app.KHzSlider_3.Limits = [-6 6];
            app.KHzLabel_3 = uilabel(app.EqualiserPanel);
            app.KHzLabel_3.Text = '8KHz';
            app.KHzSlider_4 = uislider(app.EqualiserPanel);
            app.KHzSlider_4.Limits = [-6 6];
            app.KHzLabel_4 = uilabel(app.EqualiserPanel);
            app.KHzLabel_4.Text = '16KHz';
            app.KHzSlider_5 = uislider(app.EqualiserPanel);
            app.KHzSlider_5.Limits = [-6 6];
            app.ampSlider = uislider(app.EqualiserPanel);
            app.ampSlider.Value = 50;
            app.HzSliderLabel_2 = uilabel(app.EqualiserPanel);
            app.HzSliderLabel_2.Text = 'Volume';
            app.HzSlider_5Label = uilabel(app.EqualiserPanel);
            app.HzSlider_5Label.Text = '500Hz';
            app.HzSlider_5 = uislider(app.EqualiserPanel);
            app.HzSlider_5.Limits = [-6 6];
            app.EqualisePeakButton = uibutton(app.EqualiserPanel, 'push');
            app.EqualisePeakButton.ButtonPushedFcn = createCallbackFcn(app, @EqualisePeakButtonPushed, true);
            app.EqualisePeakButton.Text = 'Equalise (Peak)';
            app.EqfourButton = uibutton(app.EqualiserPanel, 'push');
            app.EqfourButton.ButtonPushedFcn = createCallbackFcn(app, @EqualisefourierButtonPushed, true);
            app.EqfourButton.Text = 'Eq four';
            app.PlayPanel = uipanel(app.UIFigure);
            app.PlayPanel.Title = 'Play';
            app.PlayButton = uibutton(app.PlayPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.PlayPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.LoadFileButton = uibutton(app.PlayPanel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.SavePresetButton = uibutton(app.PlayPanel, 'push');
            app.SavePresetButton.ButtonPushedFcn = createCallbackFcn(app, @SavePresetButton_2Pushed, true);
            app.SavePresetButton.Text = 'SavePreset';
            app.LoadPresetButton = uibutton(app.PlayPanel, 'push');
            app.LoadPresetButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPresetButton_2Pushed, true);
            app.LoadPresetButton.Text = 'LoadPreset';
            app.EffectsPanel = uipanel(app.UIFigure);
            app.EffectsPanel.Title = 'Effects';
            app.EffectsPanel.SizeChangedFcn = createCallbackFcn(app, @EffectsPanelSizeChanged, true);
            app.WahWahButton = uibutton(app.EffectsPanel, 'push');
            app.WahWahButton.ButtonPushedFcn = createCallbackFcn(app, @WahWahButtonPushed, true);
            app.WahWahButton.Text = 'Wah Wah';
            app.FlangerButton = uibutton(app.EffectsPanel, 'push');
            app.FlangerButton.ButtonPushedFcn = createCallbackFcn(app, @FlangerButtonPushed, true);
            app.FlangerButton.Text = 'Flanger';
            app.ChorusButton = uibutton(app.EffectsPanel, 'push');
            app.ChorusButton.ButtonPushedFcn = createCallbackFcn(app, @ChorusButtonPushed, true);
            app.ChorusButton.Text = 'Chorus';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time Domain Graph')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amp')
            zlabel(app.UIAxes, 'Z')
            app.UIAxesSpectrum = uiaxes(app.UIFigure);
            title(app.UIAxesSpectrum, 'Spectral Graph')
            xlabel(app.UIAxesSpectrum, 'Freq')
            ylabel(app.UIAxesSpectrum, 'dB')
            zlabel(app.UIAxesSpectrum, 'Z')
            app.UIAxesSpectrum.XLim = [20 20000];
            app.UIAxesSpectrum.XTick = [32 64 128 256 512 1024 2048 4096 8192 16384 20000];
            app.UIAxesSpectrum.XScale = 'log';
            app.UIAxesSpectrum.XTickLabel = {'32'; '64'; '128'; '256'; '512'; '1K'; '2K'; '4K'; '8K'; '16K'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cw

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end